classdef ImageTransformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TranslateYSpinner       matlab.ui.control.Spinner
        TranslateYSpinnerLabel  matlab.ui.control.Label
        TranslateXSpinner       matlab.ui.control.Spinner
        TranslateXSpinnerLabel  matlab.ui.control.Label
        RevertButton            matlab.ui.control.Button
        ReflectoriginButton     matlab.ui.control.Button
        ReflectYButton          matlab.ui.control.Button
        ReflectXButton          matlab.ui.control.Button
        ShearYSpinner           matlab.ui.control.Spinner
        ShearYSpinnerLabel      matlab.ui.control.Label
        ShearXSpinner           matlab.ui.control.Spinner
        ShearXSpinnerLabel      matlab.ui.control.Label
        ScaleYSpinner           matlab.ui.control.Spinner
        ScaleYSpinnerLabel      matlab.ui.control.Label
        ScaleXSpinner           matlab.ui.control.Spinner
        ScaleXSpinnerLabel      matlab.ui.control.Label
        RotateSpinner           matlab.ui.control.Spinner
        RotateSpinnerLabel      matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        LoadButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
        original % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            f1 = fullfile(path,file);
            if prod(double(file) == 0) && prod(double(path) == 0)
                return
            end
            
            app.original = imread(f1);
            app.image = app.original;
            
            app.Image.ImageSource = app.image;
        end

        % Value changed function: RotateSpinner
        function RotateSpinnerValueChanged(app, event)
            value = app.RotateSpinner.Value;
            app.image = rotate(app.image, value);
            app.Image.ImageSource = app.image;
        end

        % Value changed function: ScaleXSpinner
        function ScaleXSpinnerValueChanged(app, event)
            value = app.ScaleXSpinner.Value;
            app.image = scale(app.image, value, 1);
            app.Image.ImageSource = app.image;
        end

        % Value changed function: ScaleYSpinner
        function ScaleYSpinnerValueChanged(app, event)
            value = app.ScaleYSpinner.Value;
            app.image = scale(app.image, 1, value);
            app.Image.ImageSource = app.image;
        end

        % Value changed function: ShearXSpinner
        function ShearXSpinnerValueChanged(app, event)
            value = app.ShearXSpinner.Value;
            app.image = shear(app.image, value, 0);
            app.Image.ImageSource = app.image;
        end

        % Value changed function: ShearYSpinner
        function ShearYSpinnerValueChanged(app, event)
            value = app.ShearYSpinner.Value;
            app.image = shear(app.image, 0, value);
            app.Image.ImageSource = app.image;
        end

        % Button pushed function: ReflectXButton
        function ReflectXButtonPushed(app, event)
            app.image = reflectX(app.image);
            app.Image.ImageSource = app.image;
        end

        % Button pushed function: ReflectYButton
        function ReflectYButtonPushed(app, event)
            app.image = reflectY(app.image);
            app.Image.ImageSource = app.image;
        end

        % Button pushed function: ReflectoriginButton
        function ReflectoriginButtonPushed(app, event)
            app.image = reflectXY(app.image);
            app.Image.ImageSource = app.image;
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.image = app.original;
            app.Image.ImageSource = app.image;
        end

        % Value changed function: TranslateXSpinner
        function TranslateXSpinnerValueChanged(app, event)
            value = app.TranslateXSpinner.Value;
            app.image = translate(app.image, value, 0);
            app.Image.ImageSource = app.image;
        end

        % Value changed function: TranslateYSpinner
        function TranslateYSpinnerValueChanged(app, event)
            value = app.TranslateYSpinner.Value;
            app.image = translate(app.image, 0, value);
            app.Image.ImageSource = app.image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Image = uiimage(app.UIFigure);
            app.RotateSpinnerLabel = uilabel(app.UIFigure);
            app.RotateSpinnerLabel.Text = 'Rotate';
            app.RotateSpinner = uispinner(app.UIFigure);
            app.RotateSpinner.Limits = [0 359];
            app.RotateSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateSpinnerValueChanged, true);
            app.ScaleXSpinnerLabel = uilabel(app.UIFigure);
            app.ScaleXSpinnerLabel.Text = 'Scale X';
            app.ScaleXSpinner = uispinner(app.UIFigure);
            app.ScaleXSpinner.Step = 0.1;
            app.ScaleXSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleXSpinnerValueChanged, true);
            app.ScaleYSpinnerLabel = uilabel(app.UIFigure);
            app.ScaleYSpinnerLabel.Text = 'Scale Y';
            app.ScaleYSpinner = uispinner(app.UIFigure);
            app.ScaleYSpinner.Step = 0.1;
            app.ScaleYSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleYSpinnerValueChanged, true);
            app.ShearXSpinnerLabel = uilabel(app.UIFigure);
            app.ShearXSpinnerLabel.Text = 'Shear X';
            app.ShearXSpinner = uispinner(app.UIFigure);
            app.ShearXSpinner.Step = 0.1;
            app.ShearXSpinner.ValueChangedFcn = createCallbackFcn(app, @ShearXSpinnerValueChanged, true);
            app.ShearYSpinnerLabel = uilabel(app.UIFigure);
            app.ShearYSpinnerLabel.Text = 'Shear Y';
            app.ShearYSpinner = uispinner(app.UIFigure);
            app.ShearYSpinner.Step = 0.1;
            app.ShearYSpinner.ValueChangedFcn = createCallbackFcn(app, @ShearYSpinnerValueChanged, true);
            app.ReflectXButton = uibutton(app.UIFigure, 'push');
            app.ReflectXButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectXButtonPushed, true);
            app.ReflectXButton.Text = 'Reflect X';
            app.ReflectYButton = uibutton(app.UIFigure, 'push');
            app.ReflectYButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectYButtonPushed, true);
            app.ReflectYButton.Text = 'Reflect Y';
            app.ReflectoriginButton = uibutton(app.UIFigure, 'push');
            app.ReflectoriginButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectoriginButtonPushed, true);
            app.ReflectoriginButton.Text = 'Reflect origin';
            app.RevertButton = uibutton(app.UIFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.TranslateXSpinnerLabel = uilabel(app.UIFigure);
            app.TranslateXSpinnerLabel.Text = 'Translate X';
            app.TranslateXSpinner = uispinner(app.UIFigure);
            app.TranslateXSpinner.Step = 0.1;
            app.TranslateXSpinner.ValueChangedFcn = createCallbackFcn(app, @TranslateXSpinnerValueChanged, true);
            app.TranslateYSpinnerLabel = uilabel(app.UIFigure);
            app.TranslateYSpinnerLabel.Text = 'Translate  Y';
            app.TranslateYSpinner = uispinner(app.UIFigure);
            app.TranslateYSpinner.Step = 0.1;
            app.TranslateYSpinner.ValueChangedFcn = createCallbackFcn(app, @TranslateYSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageTransformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end